classdef GanttChartApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LoadCSVButton   matlab.ui.control.Button
        GanttChartAxes  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set the title for the UIAxes
            title(app.GanttChartAxes, 'Event Schedule');
            xlabel(app.GanttChartAxes, 'Time of Day');
            ylabel(app.GanttChartAxes, 'Events');
            
            % Configure the axes to display from 8 AM to Midnight.
            app.GanttChartAxes.XLim = [6 24];
            
            % --- MODIFIED: Explicitly set major and minor ticks ---
            % Set major ticks to every two hours for clarity.
            ticks = 6:1:24;
            app.GanttChartAxes.XTick = ticks;
            
            % Create custom labels for the major ticks in 12-hour AM/PM format.
            labels = cell(size(ticks));
            for i = 1:length(ticks)
                hour = ticks(i);
                if hour == 12
                    labels{i} = '12 PM'; % Noon
                elseif hour == 24 || hour == 0
                    labels{i} = '12 AM'; % Midnight
                elseif hour > 12
                    labels{i} = sprintf('%d PM', hour - 12);
                else % hour < 12
                    labels{i} = sprintf('%d AM', hour);
                end
            end
            app.GanttChartAxes.XTickLabel = labels;
            
            % Explicitly define minor ticks to be every half hour.
            app.GanttChartAxes.XMinorTick = 'on';
            app.GanttChartAxes.XAxis.MinorTickValues = 6.5:0.5:23.5;
            app.GanttChartAxes.XMinorGrid = 'on';
            % --- END of modification ---
            
            grid(app.GanttChartAxes, 'on');
            app.GanttChartAxes.XGrid = 'on';
            app.GanttChartAxes.YGrid = 'on';
        end

        % Button pushed function: LoadCSVButton
        function LoadCSVButtonPushed(app, event)
            % Open a file selection dialog to choose a CSV file
            [file, path] = uigetfile('*.csv', 'Select Event CSV File');
            
            if isequal(file, 0)
                disp('User selected Cancel');
                return;
            end

            fullFileName = fullfile(path, file);
            
            try
                % --- NEW SIMPLIFIED CSV READING ---
                % This approach is simpler and more robust.
                opts = delimitedTextImportOptions('VariableNames', {'EventName', 'StartTime', 'EndTime'});
                % Specify that the time columns should be read as durations in HH:MM format.
                opts = setvartype(opts, {'StartTime', 'EndTime'}, 'duration');
                opts.VariableOptions(2).InputFormat = 'hh:mm';
                opts.VariableOptions(3).InputFormat = 'hh:mm';
                
                % Read the data from the CSV.
                ganttData = readtable(fullFileName, opts);

                % --- Plotting Logic ---
                if isempty(ganttData)
                    uialert(app.UIFigure, 'The selected CSV file is empty or could not be read.', 'No Data to Plot');
                    return;
                end

                % Clear the axes before drawing the new chart.
                cla(app.GanttChartAxes);
                
                numEvents = height(ganttData);
                eventNames = ganttData.EventName;
                colors = lines(numEvents); % Generate a unique color for each event.
                
                % Loop through each event in the table and plot it.
                for i = 1:numEvents
                    startTime = ganttData.StartTime(i);
                    endTime = ganttData.EndTime(i);
                    
                    % Convert duration to hours for plotting.
                    startHour = hours(startTime);
                    endHour = hours(endTime);
                    
                    % Handle events that cross midnight.
                    if endHour < startHour
                        endHour = endHour + 24;
                    end
                    
                    % Define the coordinates for the colored patch.
                    yPos = i - 0.4;
                    patch(app.GanttChartAxes, ...
                          [startHour, endHour, endHour, startHour], ...
                          [yPos, yPos, yPos + 0.8, yPos + 0.8], ...
                          colors(i,:), 'EdgeColor', 'k');
                end
                
                % Configure the Y-axis to display event names.
                app.GanttChartAxes.YTick = 1:numEvents;
                app.GanttChartAxes.YTickLabel = eventNames;
                app.GanttChartAxes.YDir = 'reverse';
                app.GanttChartAxes.YLim = [0.5, numEvents + 0.5];
                
                title(app.GanttChartAxes, 'Event Schedule');
                
            catch ex
                % Display an error message if the file processing fails.
                uialert(app.UIFigure, ['Error processing the file: ' ex.message], 'File Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GanttChartAxes = uiaxes(app.GridLayout);
            title(app.GanttChartAxes, 'Title')
            xlabel(app.GanttChartAxes, 'X')
            ylabel(app.GanttChartAxes, 'Y')
            zlabel(app.GanttChartAxes, 'Z')
            app.LoadCSVButton = uibutton(app.GridLayout, 'push');
            app.LoadCSVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCSVButtonPushed, true);
            app.LoadCSVButton.Text = 'Load Event CSV File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GanttChartApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end